classdef VideoFeatureViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        LoadvideoframesMenu    matlab.ui.container.Menu
        LoadfeaturespikesMenu  matlab.ui.container.Menu
        QuitMenu               matlab.ui.container.Menu
        MaxFrameEditField      matlab.ui.control.NumericEditField
        ofEditFieldLabel       matlab.ui.control.Label
        FrameEditField         matlab.ui.control.NumericEditField
        FrameEditFieldLabel    matlab.ui.control.Label
        FrameSlider            matlab.ui.control.Slider
        MotionfeaturesLabel    matlab.ui.control.Label
        StaticfeaturesLabel    matlab.ui.control.Label
        motion_ax              matlab.ui.control.UIAxes
        static_ax              matlab.ui.control.UIAxes
        movie_ax               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        curFrame = 1;
        frames_static = [];
        frames_diff = [];
        v %video reader object
        video_params; %parameters for video
    end
    
    methods (Access = private)
        
        function updateDisplay(app)
            vid_frame = mean(read(app.v, app.curFrame),3);
            set(app.FrameEditField,'value',app.curFrame);
            image(app.movie_ax, vid_frame);
            image(app.static_ax, app.frames_static(:,:,app.curFrame));
            imagesc(app.motion_ax, app.frames_diff(:,:,app.curFrame));
        end

        function initDisplay(app)
            colormap(app.movie_ax,'gray');
%             xlim(app.movie_ax,[1, app.v.Width]);
%             ylim(app.movie_ax,[1, app.v.Height]);
            axis(app.movie_ax,'tight');

            colormap(app.static_ax,'gray');
            axis(app.static_ax,'tight');

            colormap(app.motion_ax,'gray');
            axis(app.motion_ax,'tight');
            caxis(app.motion_ax,[-128 128])
            %TODO - put on axis (degrees)

            %TODO - make featureSpikes sections

        end
        
        function initFrameSlider(app)
            nticks = 10;
            ticks = round(linspace(1, app.v.NumFrames, nticks));
            %ticks = 1:1:app.v.NumFrames;
            set(app.FrameSlider,'limits',[1 app.v.NumFrames]);
            set(app.FrameSlider,'value',1);
            set(app.FrameSlider,'MajorTicks',ticks);
            %set(app.FrameSlider,'MajorTickLabels',{});
            set(app.FrameEditField,'value',1);
            set(app.MaxFrameEditField,'value',app.v.NumFrames);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadvideoframesMenu
        function LoadvideoframesMenuSelected(app, event)
            [fname, fpath] = uigetfile('*.mat', 'Open stored video frames file.');
            fname_full = [fpath fname];
            hash_name = strtok(fname, '_');
            if exist(fname_full,'file')
                try
                    load(fname_full,'frames_static', 'frames_diff');
                    load(sprintf('%s%s_vidParams.mat', fpath, hash_name), 'video_params');
                    app.video_params = video_params;
                    app.frames_static = frames_static;
                    app.frames_diff = frames_diff;
                    app.v = VideoReader(video_params.video_fname);
                catch ME
                    fprintf('Error loading files for %s\n', hash_name);
                    rethrow(ME);
                end
                app.initDisplay();
                initFrameSlider(app)                
                app.updateDisplay()
            end
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.curFrame = round(app.FrameSlider.Value);            
            app.updateDisplay();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'leftarrow'
                    if app.curFrame>1                        
                        app.curFrame = app.curFrame-1;
                        set(app.FrameSlider,'value',app.curFrame);
                        app.updateDisplay();
                    end
                case 'rightarrow'
                    if app.curFrame<app.v.NumFrames
                        app.curFrame = app.curFrame+1;
                        set(app.FrameSlider,'value',app.curFrame);
                        app.updateDisplay();
                    end
            end
            %fprintf('Key: %s\n', key);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            app.UIFigureCloseRequest(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadvideoframesMenu = uimenu(app.FileMenu);
            app.LoadvideoframesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadvideoframesMenuSelected, true);
            app.LoadvideoframesMenu.Text = 'Load video frames';
            app.LoadfeaturespikesMenu = uimenu(app.FileMenu);
            app.LoadfeaturespikesMenu.Text = 'Load feature spikes';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.movie_ax = uiaxes(app.UIFigure);
            title(app.movie_ax, 'Original movie')
            zlabel(app.movie_ax, 'Z')
            app.movie_ax.XTick = [];
            app.movie_ax.YTick = [];
            app.static_ax = uiaxes(app.UIFigure);
            title(app.static_ax, 'Static feature pixels')
            zlabel(app.static_ax, 'Z')
            app.static_ax.XTick = [];
            app.static_ax.YTick = [];
            app.motion_ax = uiaxes(app.UIFigure);
            title(app.motion_ax, 'Motion feature pixels')
            zlabel(app.motion_ax, 'Z')
            app.motion_ax.XTick = [];
            app.motion_ax.YTick = [];
            app.StaticfeaturesLabel = uilabel(app.UIFigure);
            app.StaticfeaturesLabel.Text = 'Static features';
            app.MotionfeaturesLabel = uilabel(app.UIFigure);
            app.MotionfeaturesLabel.Text = 'Motion features';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofEditFieldLabel = uilabel(app.UIFigure);
            app.ofEditFieldLabel.Text = 'of';
            app.MaxFrameEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoFeatureViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end